classdef ArrayDesignerAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ArrayDesignerUIFigure         matlab.ui.Figure
        ROIAxes                       matlab.ui.control.UIAxes
        ArrayAxes                     matlab.ui.control.UIAxes
        ChanHistAxes                  matlab.ui.control.UIAxes
        FileMenu                      matlab.ui.container.Menu
        LoadADfileMenu                matlab.ui.container.Menu
        SaveADfileMenu                matlab.ui.container.Menu
        ExportresultstotxtMenu        matlab.ui.container.Menu
        ToolsMenu                     matlab.ui.container.Menu
        ROIbuilderMenu                matlab.ui.container.Menu
        INPUTVARIABLESPanel           matlab.ui.container.Panel
        SelectHeadModelButton         matlab.ui.control.Button
        HeadModelPathText             matlab.ui.control.EditField
        SelectROIButton               matlab.ui.control.Button
        ROIPathText                   matlab.ui.control.EditField
        SourcesEditFieldLabel         matlab.ui.control.Label
        SourcesField                  matlab.ui.control.NumericEditField
        DetectorsEditFieldLabel       matlab.ui.control.Label
        DetectorsField                matlab.ui.control.NumericEditField
        MinSDSmmSliderLabel           matlab.ui.control.Label
        MinSDSSlider                  matlab.ui.control.Slider
        MaxgoodSDSmmLabel             matlab.ui.control.Label
        MaxGoodSDSSlider              matlab.ui.control.Slider
        MaxSDSmmSliderLabel           matlab.ui.control.Label
        MaxSDSSlider                  matlab.ui.control.Slider
        CoverageweightEditFieldLabel  matlab.ui.control.Label
        CoverageWeightField           matlab.ui.control.NumericEditField
        MinSDSField                   matlab.ui.control.NumericEditField
        MaxGoodSDSField               matlab.ui.control.NumericEditField
        MaxSDSField                   matlab.ui.control.NumericEditField
        SaveADfileButton              matlab.ui.control.Button
        MinOptSepField                matlab.ui.control.NumericEditField
        MinoptodesepmmLabel           matlab.ui.control.Label
        MinOptSepSlider               matlab.ui.control.Slider
        SolutionSpaceLabel            matlab.ui.control.Label
        SolSpaceButtonGroup           matlab.ui.container.ButtonGroup
        Ten25Button                   matlab.ui.control.RadioButton
        Ten5Button                    matlab.ui.control.RadioButton
        LoadADfileButton              matlab.ui.control.Button
        RunButton                     matlab.ui.control.Button
        ExportResultstotxtButton      matlab.ui.control.Button
        ArrayDesignerOutputInfoTextAreaLabel  matlab.ui.control.Label
        OutputInfoText                matlab.ui.control.TextArea
        ROIViewLabel                  matlab.ui.control.Label
        ArrayViewLabel                matlab.ui.control.Label
    end

    
    properties (Access = public)
        repoPath = fileparts(mfilename('fullpath'));
        %initialise AD structure with empties except default head mode path
        AD = struct('inputs',struct('nS',1,'nD',1,'minRhoOpt',10,'minRho',15,'maxGoodRho',30,'maxRho',45,'coverageWeight',0,'pathnameAD',fileparts(mfilename('fullpath')),'pathnameHeadModel',[],'ten5flag',0,'pathnameROI',[],'pathnameOutput',[fullfile(fileparts(mfilename('fullpath')),'Results')]),... 
            'results',struct('source',[],'detector',[],'signal',[],'signalPerc',[],'coveragePerc',[],'runtime',[],'nChannels',[],'measList',[],'channelDists',[]));
        pathnameMSHS;
        hFigTxtBox;
        gmSurfaceMesh;
        ROI;
        currViewAngROI;
        currViewAngArray;
    end
    
    methods (Access = private)
        
        function results = updateAll(app)
            
            %Update all the inputs components
            updateVariables(app);
            
            %Update figures
            updateFigures(app);
        end
        
        function results = updateVariables(app)
            %Update all the inputs components
            [~,tmp,ext] = fileparts(app.AD.inputs.pathnameHeadModel);
            app.HeadModelPathText.Value = [tmp];
            
            [~,tmp,ext] = fileparts(app.AD.inputs.pathnameROI);
            app.ROIPathText.Value = [tmp];
            
            if ~isempty(app.AD.inputs.pathnameROI)
                app.ROI = loadROI(app.AD.inputs.pathnameROI);
            end
            
            %Update mshs pathname
            if ~isempty(app.AD.inputs.pathnameHeadModel)
                app.pathnameMSHS = getMSHSpath(app.AD.inputs.pathnameHeadModel);
                %Update gmSurfaceMesh
                tmp = load(app.pathnameMSHS,'gmSurfaceMesh','-mat');
                if length(tmp.gmSurfaceMesh.node)>20e3
                    warning('The selected mesh has a GM surface of >20,000 nodes. We recommend downsampling this mesh to maintain speed');
                end
                app.gmSurfaceMesh = tmp.gmSurfaceMesh;
            end
            
            %Update sol space selection
            if app.AD.inputs.ten5flag
                app.Ten5Button.Value = 1;
            else
                app.Ten25Button.Value = 1;
            end
                        
            app.SourcesField.Value = app.AD.inputs.nS;
            
            app.DetectorsField.Value = app.AD.inputs.nD;
            
            app.CoverageWeightField.Value = app.AD.inputs.coverageWeight;
            
            app.MinSDSField.Value = app.AD.inputs.minRhoOpt;
            app.MinSDSSlider.Value = app.AD.inputs.minRhoOpt;
            
            app.MaxGoodSDSField.Value = app.AD.inputs.maxGoodRho;
            app.MaxGoodSDSSlider.Value = app.AD.inputs.maxGoodRho;
            
            app.MaxSDSField.Value = app.AD.inputs.maxRho;
            app.MaxSDSSlider.Value = app.AD.inputs.maxRho;
        end
        
        function results = resetResults(app)
            %Reset figures and app.AD.outputs
            cla(app.ROIAxes)
            cla(app.ArrayAxes)
            app.currViewAngROI = [];
            app.currViewAngArray = [];
            app.OutputInfoText.Value = '';
            app.AD.results = struct('source',[],'detector',[],'signal',[],'signalPerc',[],'coveragePerc',[],'runtime',[],'nChannels',[],'measList',[],'channelDists',[]);
        end
        
        function results = updateFigures(app)
            
            %Update ROI plot
            app.currViewAngROI = app.ROIAxes.View;
            cla(app.ROIAxes);
            if ~isempty(app.AD.inputs.pathnameROI) && ~isempty(app.AD.inputs.pathnameHeadModel)
                plotROI(app.gmSurfaceMesh,app.ROI,'hAxes',app.ROIAxes,'view',app.currViewAngROI);
            end
            
            app.currViewAngArray = app.ArrayAxes.View;
            cla(app.ArrayAxes);
            colorbar(app.ArrayAxes,'off')
            %Update Array plot
            if ~isempty(app.AD.results.source)
                plotArray(app.AD,'hAxes',app.ArrayAxes,'view',app.currViewAngArray);
                %Set ROI axis to match array axis
                ylim(app.ROIAxes,app.ArrayAxes.YLim);
                xlim(app.ROIAxes,app.ArrayAxes.XLim);
                zlim(app.ROIAxes,app.ArrayAxes.ZLim);
            end            
            
            cla(app.ChanHistAxes);
            %Update Channel Histogram and text info
            if ~isempty(app.AD.results.source)
                plotChanHist(app.AD,'hAxes',app.ChanHistAxes);
                
                output{1} = ['Coverage = ' num2str(app.AD.results.coveragePerc,'%0.1f') '%'];
                output{2} = ['Rel. sensitivity = ' num2str(app.AD.results.signalPerc,'%0.1f') '%'];
                output{3} = ['Sensitivity = ' num2str(app.AD.results.signal,'%0.1f') ' mm'];
                output{4} = ['nChannels = ' num2str(app.AD.results.nChannels)];
                output{5} = ['Time = ' num2str(app.AD.results.runtime,'%0.1f') ' s'];
                
                app.OutputInfoText.Value = output;
            end
            
        end
        
        function results = progSelectHeadModel(app,fullpath) %To programmatically select and handle a head model file.
            [pathname,filename,ext] = fileparts(fullpath);
            pathnameHeadModeltmp = fullfile(pathname,filename);
            mshstmp = load(fullfile([pathnameHeadModeltmp ext]),'-mat');
            
            makePMDFflag = 0;
            makeSolutionSpaceFlag = 0;
            resetResults(app);
            
            %Check if there is a folder pwd/HeadModels/filename. If not,
            %create and save mshs there
            if ~exist(fullfile(app.repoPath,'HeadModels',filename),'dir')
               mkdir(fullfile(app.repoPath,'HeadModels',filename));
               save(pathnameHeadModeltmp,'-struct','mshstmp');
               app.AD.inputs.pathnameHeadModel = fullfile(app.repoPath,'HeadModels',filename);
               makeSolutionSpaceFlag = 1;
               makePMDFflag = 1;
            
            %If headmodel exists with this name, but specified path is different. Warn and overwrite
            elseif ~strcmpi(fullfile(app.repoPath,'HeadModels',filename,filename),pathnameHeadModeltmp)  
                selection = uiconfirm(app.ArrayDesignerUIFigure,'A head model with this name already exists in ArrayDesigner','Head Model','Options',{'Use Existing','Overwrite Existing'},'DefaultOption',1,...
                        'Icon','warning');
                    
                    if strcmpi(selection,'Use Existing')
                        app.AD.inputs.pathnameHeadModel = fullfile(app.repoPath,'HeadModels',filename);
                        %Check if PMDFs exist
                        if ~exist(fullfile(app.repoPath,'HeadModels',filename,'PMDFs','PMDFs.data'),'file')
                            makePMDFflag = 1;
                        end
                        if ~exist(fullfile(app.repoPath,'HeadModels',filename,'Utils','scalpSolutionSpace_10_2p5.txt'),'file')
                            makeSolutionSpaceFlag = 1;
                        end
                    elseif strcmpi(selection,'Overwrite Existing')
                        save(fullfile(app.repoPath,'HeadModels',[filename ext]),'-struct','mshstmp');
                        app.AD.inputs.pathnameHeadModel = fullfile(app.repoPath,'HeadModels',filename);
                        %Assume PMDFs do not exist
                        makePMDFflag = 1;
                        makeSolutionSpaceFlag = 1;
                    end
            else %Headmodel is existing one selected from HeadModels folder
                %Check if PMDFs exist
                if ~exist(fullfile(app.repoPath,'HeadModels',filename,'PMDFs','PMDFs.data'),'file')
                    makePMDFflag = 1;
                end
                if ~exist(fullfile(app.repoPath,'HeadModels',filename,'Utils','scalpSolutionSpace_10_2p5.txt'),'file')
                    makeSolutionSpaceFlag = 1;
                end
                %Specify pathnameHeadModel as that selected
                app.AD.inputs.pathnameHeadModel = fullfile(app.repoPath,'HeadModels',filename);
            end
            
            %Update mshs pathname
            app.pathnameMSHS = fullfile(app.AD.inputs.pathnameHeadModel,[filename ext]);
                   
            %Update gmSurfaceMesh
            tmp = load(app.pathnameMSHS,'gmSurfaceMesh','-mat');
            if length(tmp.gmSurfaceMesh.node)>20e3
                warning('The selected mesh has a GM surface of >20,000 nodes. We recommend downsampling this mesh to maintain speed');
            end
            app.gmSurfaceMesh = tmp.gmSurfaceMesh;
            
            %Create solutions spaces if needed
            if makeSolutionSpaceFlag
                selection = uiconfirm(app.ArrayDesignerUIFigure,'The selected head model has no defined scalp solution space, would you like to compute this now?','Solution space','Options',{'Yes','Cancel'},'DefaultOption',1,...
                    'Icon','warning');
                if strcmpi(selection,'Yes')
                    %Create solution space - scalpSolutionSpace_10_2p5.txt
                    getSolutionSpace(app.AD.inputs.pathnameHeadModel,app.pathnameMSHS);
                    
                    %Write gmPos.txt
                    writeGMPos(app.gmSurfaceMesh,app.AD.inputs.pathnameHeadModel);
                end
            end 
            
            %Make PMDFs if needed
            if makePMDFflag
                selection = uiconfirm(app.ArrayDesignerUIFigure,'There are no PMDFs stored for this model, would you like to compute them now? This process requires TOAST++ and takes ~45 minutes on a 16Gb MacBook Pro. It only needs to be done once when you first load a new head model.','Head Model','Options',{'Yes','Cancel'},'DefaultOption',1,...
                        'Icon','warning');
                    if strcmpi(selection,'Yes')
                        %CALL PMDF BUILDER
                        computeFluenceToast(app.pathnameMSHS,app.AD.inputs.pathnameHeadModel,fullfile(app.repoPath,'HeadModels',filename,'PMDFs'))
                        computePMDFs(fullfile(app.repoPath,'HeadModels',filename,'PMDFs'),app.pathnameMSHS,[0 60],1e-6)
                    end
            end
                         
            %Update text field
            app.HeadModelPathText.Value = [filename ext];
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Set and load default Head Model variables
            fullpath = fullfile(app.repoPath,'HeadModels','MNI152_Symmetric','MNI152_Symmetric.mshs');
            progSelectHeadModel(app,fullpath)
        end

        % Button pushed function: SelectHeadModelButton
        function SelectHeadModelButtonPushed(app, event)
            [filename, pathname, ~] = uigetfile(fullfile(app.repoPath,'*.mshs'),'Select .mshs file');
            if filename==0
                return
            end
            fullpath = [pathname filename];
            [pathname,filename,ext] = fileparts(fullpath);
            pathnameHeadModeltmp = fullfile(pathname,filename);
            mshstmp = load(fullfile([pathnameHeadModeltmp ext]),'-mat');
            
            makePMDFflag = 0;
            makeSolutionSpaceFlag = 0;
            resetResults(app);
            
            %Check if there is a folder pwd/HeadModels/filename. If not,
            %create and save mshs there
            if ~exist(fullfile(app.repoPath,'HeadModels',filename),'dir')
               mkdir(fullfile(app.repoPath,'HeadModels',filename));
               save(pathnameHeadModeltmp,'-struct','mshstmp');
               app.AD.inputs.pathnameHeadModel = fullfile(app.repoPath,'HeadModels',filename);
               makeSolutionSpaceFlag = 1;
               makePMDFflag = 1;
            
            %If headmodel exists with this name, but specified path is different. Warn and overwrite
            elseif ~strcmpi(fullfile(app.repoPath,'HeadModels',filename,filename),pathnameHeadModeltmp)  
                selection = uiconfirm(app.ArrayDesignerUIFigure,'A head model with this name already exists in ArrayDesigner','Head Model','Options',{'Use Existing','Overwrite Existing'},'DefaultOption',1,...
                        'Icon','warning');
                    
                    if strcmpi(selection,'Use Existing')
                        app.AD.inputs.pathnameHeadModel = fullfile(app.repoPath,'HeadModels',filename);
                        %Check if PMDFs exist
                        if ~exist(fullfile(app.repoPath,'HeadModels',filename,'PMDFs','PMDFs.data'),'file')
                            makePMDFflag = 1;
                        end
                        if ~exist(fullfile(app.repoPath,'HeadModels',filename,'Utils','scalpSolutionSpace_10_2p5.txt'),'file')
                            makeSolutionSpaceFlag = 1;
                        end
                    elseif strcmpi(selection,'Overwrite Existing')
                        save(fullfile(app.repoPath,'HeadModels',[filename ext]),'-struct','mshstmp');
                        app.AD.inputs.pathnameHeadModel = fullfile(app.repoPath,'HeadModels',filename);
                        %Assume PMDFs do not exist
                        makePMDFflag = 1;
                        makeSolutionSpaceFlag = 1;
                    end
            else %Headmodel is existing one selected from HeadModels folder
                %Check if PMDFs exist
                if ~exist(fullfile(app.repoPath,'HeadModels',filename,'PMDFs','PMDFs.data'),'file')
                    makePMDFflag = 1;
                end
                if ~exist(fullfile(app.repoPath,'HeadModels',filename,'Utils','scalpSolutionSpace_10_2p5.txt'),'file')
                    makeSolutionSpaceFlag = 1;
                end
                %Specify pathnameHeadModel as that selected
                app.AD.inputs.pathnameHeadModel = fullfile(app.repoPath,'HeadModels',filename);
            end
            
            %Update mshs pathname
            app.pathnameMSHS = fullfile(app.AD.inputs.pathnameHeadModel,[filename ext]);
                   
            %Update gmSurfaceMesh
            tmp = load(app.pathnameMSHS,'gmSurfaceMesh','-mat');
            if length(tmp.gmSurfaceMesh.node)>20e3
                warning('The selected mesh has a GM surface of >20,000 nodes. We recommend downsampling this mesh to maintain speed');
            end
            app.gmSurfaceMesh = tmp.gmSurfaceMesh;
            
            %Create solutions spaces if needed
            if makeSolutionSpaceFlag
                selection = uiconfirm(app.ArrayDesignerUIFigure,'The selected head model has no defined scalp solution space, would you like to compute this now?','Solution space','Options',{'Yes','Cancel'},'DefaultOption',1,...
                    'Icon','warning');
                if strcmpi(selection,'Yes')
                    %Create solution space - scalpSolutionSpace_10_2p5.txt
                    getSolutionSpace(app.AD.inputs.pathnameHeadModel,app.pathnameMSHS);
                    
                    %Write gmPos.txt
                    writeGMPos(app.gmSurfaceMesh,app.AD.inputs.pathnameHeadModel);
                end
            end 
            
            %Make PMDFs if needed
            if makePMDFflag
                selection = uiconfirm(app.ArrayDesignerUIFigure,'There are no PMDFs stored for this model, would you like to compute them now? This process requires TOAST++ and takes ~45 minutes on a 16Gb MacBook Pro. It only needs to be done once when you first load a new head model.','Head Model','Options',{'Yes','Cancel'},'DefaultOption',1,...
                        'Icon','warning');
                    if strcmpi(selection,'Yes')
                        %CALL PMDF BUILDER
                        computeFluenceToast(app.pathnameMSHS,app.AD.inputs.pathnameHeadModel,fullfile(app.repoPath,'HeadModels',filename,'PMDFs'))
                        computePMDFs(fullfile(app.repoPath,'HeadModels',filename,'PMDFs'),app.pathnameMSHS,[0 60],1e-6)
                    end
            end
                         
            %Update text field
            app.HeadModelPathText.Value = [filename ext];
            
        end

        % Button pushed function: SelectROIButton
        function SelectROIButtonPushed(app, event)
            [filename, pathname, ~] = uigetfile(fullfile(app.repoPath,'*.txt'),'Select ROI txt file',app.AD.inputs.pathnameHeadModel);
            if ~filename
                selection = uiconfirm(app.ArrayDesignerUIFigure,'No ROI selected. Would you like to build one?','PMDFs','Options',{'Yes','No'},'DefaultOption',1,...
                    'Icon','question');
                if strcmpi(selection,'Yes')
                    tmp = ROIBuilderAPP(app.AD.inputs.pathnameROI,app.AD.inputs.pathnameHeadModel); 
                end
                return
            else
                fullpath = fullfile(pathname,filename);
                [pathname,filename,ext] = fileparts(fullpath);
                app.AD.inputs.pathnameROI = fullfile(pathname,[filename ext]);
                
                %Update text field
                app.ROIPathText.Value = [filename ext];
            end
            
            %load ROI for use
            app.ROI = loadROI(app.AD.inputs.pathnameROI);
            
            %Update figures
            resetResults(app);
            updateFigures(app)
        end

        % Value changed function: SourcesField
        function SourcesFieldValueChanged(app, event)
            value = app.SourcesField.Value;
            app.AD.inputs.nS = value;
        end

        % Value changed function: DetectorsField
        function DetectorsFieldValueChanged(app, event)
            value = app.DetectorsField.Value;
            app.AD.inputs.nD = value;
        end

        % Value changed function: CoverageWeightField
        function CoverageWeightFieldValueChanged(app, event)
            value = app.CoverageWeightField.Value;
            app.AD.inputs.coverageWeight = value;
        end

        % Value changing function: MinSDSSlider
        function MinSDSSliderValueChanging(app, event)
            changingValue = event.Value;
            app.MinSDSField.Value = round(10*changingValue)/10;
            app.AD.inputs.minRho = round(10*changingValue)/10;
            if changingValue < app.MinOptSepField.Value
                app.MinOptSepField.Value = round(10*changingValue)/10;
                app.MinOptSepSlider.Value = round(10*changingValue)/10;
            end
            if changingValue >= app.MaxGoodSDSField.Value
                app.MaxGoodSDSField.Value = round(10*changingValue)/10;
                app.MaxGoodSDSSlider.Value = round(10*changingValue)/10;
            end
            if changingValue >= app.MaxSDSField.Value
                app.MaxSDSField.Value = round(10*changingValue)/10;
                app.MaxSDSSlider.Value = round(10*changingValue)/10;
            end         
        end

        % Value changed function: MinSDSField
        function MinSDSFieldValueChanged(app, event)
            value = app.MinSDSField.Value;
            app.MinSDSField.Value = round(value)/10;
            app.MinSDSSlider.Value = round(value)/10;
            app.AD.inputs.minRho = round(value)/10;
        end

        % Value changing function: MaxGoodSDSSlider
        function MaxGoodSDSSliderValueChanging(app, event)
            changingValue = event.Value;
            app.MaxGoodSDSField.Value = round(10*changingValue)/10;
            app.AD.inputs.maxGoodRho = round(10*changingValue)/10;
            if changingValue < app.MinOptSepField.Value
                app.MinOptSepField.Value = round(10*changingValue)/10;
                app.MinOptSepSlider.Value = round(10*changingValue)/10;
            end
            if changingValue < app.MinSDSField.Value
                app.MinSDSField.Value = round(10*changingValue)/10;
                app.MinSDSSlider.Value = round(10*changingValue)/10;
            end
            if changingValue >= app.MaxSDSField.Value
                app.MaxSDSField.Value = round(10*changingValue)/10;
                app.MaxSDSSlider.Value = round(10*changingValue)/10;
            end

        end

        % Value changed function: MaxGoodSDSField
        function MaxGoodSDSFieldValueChanged(app, event)
            value = app.MaxGoodSDSField.Value;
            app.MaxGoodSDSField.Value = round(value)/10;
            app.MaxGoodSSlider.Value = round(value)/10;
            app.AD.inputs.maxGoodRho = round(value)/10;   
            if changingValue < app.MinOptSepField.Value
                app.MinOptSepField.Value = round(10*changingValue)/10;
                app.MinOptSepSlider.Value = round(10*changingValue)/10;
            end
            if changingValue < app.MinSDSField.Value
                app.MinSDSField.Value = round(10*changingValue)/10;
                app.MinSDSSlider.Value = round(10*changingValue)/10;
            end
            if changingValue > app.MaxSDSField.Value
                app.MaxGoodSDSField.Value = round(10*changingValue)/10;
                app.MaxGoodSDSSlider.Value = round(10*changingValue)/10;
            end
        end

        % Value changing function: MaxSDSSlider
        function MaxSDSSliderValueChanging(app, event)
            changingValue = event.Value;
            app.MaxSDSField.Value = round(10*changingValue)/10;
            app.AD.inputs.maxRho = round(10*changingValue)/10;
            if changingValue < app.MinOptSepField.Value
                app.MinOptSepField.Value = round(10*changingValue)/10;
                app.MinOptSepSlider.Value = round(10*changingValue)/10;
            end
            if changingValue < app.MinSDSField.Value
                app.MinSDSField.Value = round(10*changingValue)/10;
                app.MinSDSSlider.Value = round(10*changingValue)/10;
            end
            if changingValue < app.MaxGoodSDSField.Value
                app.MaxGoodSDSField.Value = round(10*changingValue)/10;
                app.MaxGoodSDSSlider.Value = round(10*changingValue)/10;
            end
        end

        % Value changed function: MaxSDSField
        function MaxSDSFieldValueChanged(app, event)
            value = app.MaxSDSField.Value;
            app.MaxSDSField.Value = round(value)/10;
            app.MaxSDSlider.Value = round(value)/10;
            app.AD.inputs.maxRho = round(value)/10;                
        end

        % Callback function: SaveADfileButton, SaveADfileMenu
        function SaveADfileButtonPushed(app, event)
            outname = fullfile(app.repoPath,'Results',['ArrayDesigner_' datestr(now,'YYYYmmddHHMMss') '.AD']);
            [filename, pathname, ~] = uiputfile('*.AD','Save AD file as...',outname);
            if filename==0
                return
            end
            AD = app.AD;
            if filename
                save(fullfile(pathname,filename),'-struct','AD');
            end
        end

        % Value changing function: MinOptSepSlider
        function MinOptSepSliderValueChanging(app, event)
            changingValue = event.Value;
            app.MinOptSepField.Value = round(10*changingValue)/10;
            app.AD.inputs.minRhoOpt = round(10*changingValue)/10;  
            if app.MinSDSField.Value < round(10*changingValue)/10 
               app.MinSDSField.Value = round(10*changingValue)/10; 
               app.MinSDSSlider.Value = round(10*changingValue)/10; 
            end
            if app.MaxGoodSDSField.Value < round(10*changingValue)/10
                app.MaxGoodSDSField.Value = round(10*changingValue)/10;
                 app.MaxGoodSDSSlider.Value = round(10*changingValue)/10;
            end
            if app.MaxSDSField.Value < round(10*changingValue)/10
                app.MaxSDSField.Value = round(10*changingValue)/10;
                app.MaxSDSSlider.Value = round(10*changingValue)/10;
            end
        end

        % Value changed function: MinOptSepField
        function MinOptSepFieldValueChanged(app, event)
            value = app.MinOptSepField.Value;
            app.MinOptSepField.Value = round(value)/10;
            app.MinOptSepSlider.Value = round(value)/10;
            app.AD.inputs.minRhoOpt = round(value)/10;              
        end

        % Callback function: ExportResultstotxtButton, 
        % ExportresultstotxtMenu
        function ExportResultstotxtButtonPushed(app, event)
            %Export AD.results to text file (using labels)
            [filename, pathname, ~] = uiputfile('*.txt','Save Array Designer results .txt file as...',app.repoPath);
            if filename==0
                return
            end
            fid = fopen(fullfile(pathname,filename),'w');
            fprintf(fid,'%s\n',['DateTime = ' datestr(now,'YYYYmmddHHMMss')]);
            for i = 1:length(app.OutputInfoText.Value)
                fprintf(fid,'%s\n',app.OutputInfoText.Value{i});
            end
            fprintf(fid,'%s','Sources: ');
            for i = 1:length(app.AD.results.sourceLabels)
                fprintf(fid,'%s; ',app.AD.results.sourceLabels{i});
            end
            fprintf(fid,'\n%s','Detectors: ');
            for i = 1:length(app.AD.results.detectorLabels)
                fprintf(fid,'%s; ',app.AD.results.detectorLabels{i});
            end
            fclose(fid);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %Check all inputs are populated
            fields = fieldnames(app.AD.inputs);
            for i = 1:length(fields)
                if isempty(getfield(app.AD.inputs,fields{i}))
                    selection = uiconfirm(app.ArrayDesignerUIFigure,['Missing: ' fields{i} '. All inputs must be provided prior to running'],'Run Error','Options',{'Ok'},'DefaultOption',1,...
                        'Icon','warning');
                    return
                end
            end
            
            %Hack to allow 10-5 solution space as well as 10-2.5 without having to
            %change format of ROI files. If solution space 10-5 is requested, load ROI, remove
            %solSpaceNodes that are only 10-2.5 and resave a temporary ROI.txt, then delete.
            if app.AD.inputs.ten5flag
                ROItmp = loadROI(app.AD.inputs.pathnameROI);
                load(fullfile(app.AD.inputs.pathnameHeadModel,'Utils','ten2p5_to_ten5.mat'));
                %exclude ROItmp.solSpaceNodeInd that are not == 1 in ten2pt_to_ten5
                tmp = ROItmp.solutionSpaceNodeList;
                tmp2 = tmp(ismember(tmp,find(ten2p5_to_ten5)));
                ROItmp.solutionSpaceNodeList = tmp2;
                
                %create temporary ROI with only 10-5 positions in solution space, then delete
                pathnameROItmp = fullfile(app.AD.inputs.pathnameHeadModel,'Utils','tmpROI.txt');
                writeROI(ROItmp,pathnameROItmp);
                ADtmp = app.AD;
                ADtmp.inputs.pathnameROI = pathnameROItmp;
            else
                ADtmp = app.AD;
            end
            
            %% Check there are more solutionSpace locations than nS + nD
            ROItmp = loadROI(ADtmp.inputs.pathnameROI);
            if length(ROItmp.solutionSpaceNodeList) < (ADtmp.inputs.nS + ADtmp.inputs.nD)
               uialert(app.ArrayDesignerUIFigure,'The solution space associated with the selected ROI contains fewer points than the total number of optodes requested. Either decrease number of optodes, increases size of ROI or increase density of solution space.','Too many optodes');
               return
            end
            
            %% Create status box
            delete(app.hFigTxtBox); %Remove if exists to avoid window build-up
            app.hFigTxtBox = uifigure();
            txtBox = uitextarea(app.hFigTxtBox);
            txtBox.Parent;
            app.hFigTxtBox.Name = 'Status';
            txtBox.Position = [5 5 app.hFigTxtBox.Position(3)-10 app.hFigTxtBox.Position(4)-10];
            statString = {'Running Array Designer GRASP algorithm...'};
            if exist('txtBox')
                txtBox.Value = statString;
            end
            drawnow            
            
            %% RUN ARRAY DESIGNER GRASP ALGORITHM
            [ADtmp,readOut] = ArrayDesignerGRASP(ADtmp);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %Update status box
            statString{end+1} = readOut;
            sourceLabelList = 'Sources: ';
            for i = 1:length(ADtmp.results.sourceLabels)
                sourceLabelList = [sourceLabelList ADtmp.results.sourceLabels{i} '; '];
            end
            statString{end+1} = sourceLabelList;
            detectorLabelList = 'Detectors: ';
            for i = 1:length(ADtmp.results.detectorLabels)
                detectorLabelList = [detectorLabelList ADtmp.results.detectorLabels{i} '; '];
            end
            statString{end+1} = detectorLabelList;
            if exist('txtBox')
                txtBox.Value = statString;
            end
            drawnow
            
            %Reset ROI to original (needed for 10-5 hack), update app.AD
            ADtmp.inputs.pathnameROI = app.AD.inputs.pathnameROI;
            app.AD = ADtmp;
            if exist(fullfile(app.AD.inputs.pathnameHeadModel,'Utils','tmpROI.txt'),'file')
                delete(fullfile(app.AD.inputs.pathnameHeadModel,'Utils','tmpROI.txt')); 
            end
            
            %Save AD file to default location
            outname = fullfile(app.repoPath,'Results',['ArrayDesigner_' datestr(now,'YYYYmmddHHMMss') '.AD']);
            AD = app.AD;
            save(outname,'-struct','AD');
            statString{end+1} = ['AD file saved to: ' outname];
            if exist('txtBox')
                txtBox.Value = statString;
            end
            drawnow
            
            %Update figures
            updateFigures(app);
            
        end

        % Menu selected function: ROIbuilderMenu
        function ROIbuilderMenuSelected(app, event)
            ROIBuilderAPP(app.AD.inputs.pathnameROI,app.AD.inputs.pathnameHeadModel); 
        end

        % Selection changed function: SolSpaceButtonGroup
        function SolSpaceButtonGroupSelectionChanged(app, event)
            selectedButton = app.SolSpaceButtonGroup.SelectedObject;
            if ~isempty(app.AD.inputs.pathnameHeadModel)
                if strcmpi(selectedButton.Text,'Ten-2.5')
                    app.AD.inputs.ten5flag = 0;
                elseif strcmpi(selectedButton.Text,'Ten-5')
                    app.AD.inputs.ten5flag = 1;
                end
            end
            
        end

        % Callback function: LoadADfileButton, LoadADfileMenu
        function LoadADfileButtonPushed(app, event)
            [filename, pathname, ~] = uigetfile(fullfile(app.repoPath,'Results','*.AD'),'Select AD file');
            if filename==0
                return
            end
            app.AD = load(fullfile(pathname,filename),'-mat');
            updateAll(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ArrayDesignerUIFigure = uifigure('Visible', 'off');
            app.ArrayDesignerUIFigure.Name = 'Array Designer';
            app.ROIAxes = uiaxes(app.ArrayDesignerUIFigure);
            title(app.ROIAxes, '')
            xlabel(app.ROIAxes, '')
            ylabel(app.ROIAxes, '')
            app.ROIAxes.XTick = [];
            app.ROIAxes.YTick = [];
            app.ArrayAxes = uiaxes(app.ArrayDesignerUIFigure);
            title(app.ArrayAxes, '')
            xlabel(app.ArrayAxes, '')
            ylabel(app.ArrayAxes, '')
            app.ArrayAxes.XTick = [];
            app.ArrayAxes.YTick = [];
            app.ChanHistAxes = uiaxes(app.ArrayDesignerUIFigure);
            title(app.ChanHistAxes, 'Channel SDS histogram')
            xlabel(app.ChanHistAxes, 'SDS (mm)')
            ylabel(app.ChanHistAxes, '#Channels')
            app.FileMenu = uimenu(app.ArrayDesignerUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadADfileMenu = uimenu(app.FileMenu);
            app.LoadADfileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadADfileButtonPushed, true);
            app.LoadADfileMenu.Text = 'Load AD file';
            app.SaveADfileMenu = uimenu(app.FileMenu);
            app.SaveADfileMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveADfileButtonPushed, true);
            app.SaveADfileMenu.Text = 'Save AD file';
            app.ExportresultstotxtMenu = uimenu(app.FileMenu);
            app.ExportresultstotxtMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportResultstotxtButtonPushed, true);
            app.ExportresultstotxtMenu.Text = 'Export results to .txt';
            app.ToolsMenu = uimenu(app.ArrayDesignerUIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.ROIbuilderMenu = uimenu(app.ToolsMenu);
            app.ROIbuilderMenu.MenuSelectedFcn = createCallbackFcn(app, @ROIbuilderMenuSelected, true);
            app.ROIbuilderMenu.Text = 'ROI builder';
            app.INPUTVARIABLESPanel = uipanel(app.ArrayDesignerUIFigure);
            app.INPUTVARIABLESPanel.Title = 'INPUT VARIABLES';
            app.SelectHeadModelButton = uibutton(app.INPUTVARIABLESPanel, 'push');
            app.SelectHeadModelButton.ButtonPushedFcn = createCallbackFcn(app, @SelectHeadModelButtonPushed, true);
            app.SelectHeadModelButton.Text = 'Select Head Model';
            app.HeadModelPathText = uieditfield(app.INPUTVARIABLESPanel, 'text');
            app.HeadModelPathText.Editable = 'off';
            app.HeadModelPathText.Value = 'MNI152_Symmetric';
            app.SelectROIButton = uibutton(app.INPUTVARIABLESPanel, 'push');
            app.SelectROIButton.ButtonPushedFcn = createCallbackFcn(app, @SelectROIButtonPushed, true);
            app.SelectROIButton.Text = 'Select ROI';
            app.ROIPathText = uieditfield(app.INPUTVARIABLESPanel, 'text');
            app.ROIPathText.Editable = 'off';
            app.SourcesEditFieldLabel = uilabel(app.INPUTVARIABLESPanel);
            app.SourcesEditFieldLabel.Text = '# Sources';
            app.SourcesField = uieditfield(app.INPUTVARIABLESPanel, 'numeric');
            app.SourcesField.Limits = [1 100];
            app.SourcesField.ValueChangedFcn = createCallbackFcn(app, @SourcesFieldValueChanged, true);
            app.SourcesField.Value = 1;
            app.DetectorsEditFieldLabel = uilabel(app.INPUTVARIABLESPanel);
            app.DetectorsEditFieldLabel.Text = '# Detectors';
            app.DetectorsField = uieditfield(app.INPUTVARIABLESPanel, 'numeric');
            app.DetectorsField.Limits = [1 100];
            app.DetectorsField.ValueChangedFcn = createCallbackFcn(app, @DetectorsFieldValueChanged, true);
            app.DetectorsField.Value = 1;
            app.MinSDSmmSliderLabel = uilabel(app.INPUTVARIABLESPanel);
            app.MinSDSmmSliderLabel.Text = 'Min. SDS (mm)';
            app.MinSDSSlider = uislider(app.INPUTVARIABLESPanel);
            app.MinSDSSlider.Limits = [1 30];
            app.MinSDSSlider.MajorTicks = [1 10 20 30 40 50];
            app.MinSDSSlider.ValueChangingFcn = createCallbackFcn(app, @MinSDSSliderValueChanging, true);
            app.MinSDSSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50];
            app.MinSDSSlider.Value = 15;
            app.MaxgoodSDSmmLabel = uilabel(app.INPUTVARIABLESPanel);
            app.MaxgoodSDSmmLabel.Text = 'Max. good SDS (mm)';
            app.MaxGoodSDSSlider = uislider(app.INPUTVARIABLESPanel);
            app.MaxGoodSDSSlider.Limits = [20 50];
            app.MaxGoodSDSSlider.MajorTicks = [1 10 20 30 40 50];
            app.MaxGoodSDSSlider.ValueChangingFcn = createCallbackFcn(app, @MaxGoodSDSSliderValueChanging, true);
            app.MaxGoodSDSSlider.MinorTicks = [20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60];
            app.MaxGoodSDSSlider.Value = 30;
            app.MaxSDSmmSliderLabel = uilabel(app.INPUTVARIABLESPanel);
            app.MaxSDSmmSliderLabel.Text = 'Max. SDS (mm)';
            app.MaxSDSSlider = uislider(app.INPUTVARIABLESPanel);
            app.MaxSDSSlider.Limits = [20 60];
            app.MaxSDSSlider.MajorTicks = [1 10 20 30 40 50 60];
            app.MaxSDSSlider.ValueChangingFcn = createCallbackFcn(app, @MaxSDSSliderValueChanging, true);
            app.MaxSDSSlider.MinorTicks = [20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60];
            app.MaxSDSSlider.Value = 45;
            app.CoverageweightEditFieldLabel = uilabel(app.INPUTVARIABLESPanel);
            app.CoverageweightEditFieldLabel.Text = {'Coverage '; 'weight'};
            app.CoverageWeightField = uieditfield(app.INPUTVARIABLESPanel, 'numeric');
            app.CoverageWeightField.Limits = [0 100];
            app.CoverageWeightField.ValueChangedFcn = createCallbackFcn(app, @CoverageWeightFieldValueChanged, true);
            app.MinSDSField = uieditfield(app.INPUTVARIABLESPanel, 'numeric');
            app.MinSDSField.Limits = [0 Inf];
            app.MinSDSField.ValueDisplayFormat = '%2.1f';
            app.MinSDSField.ValueChangedFcn = createCallbackFcn(app, @MinSDSFieldValueChanged, true);
            app.MinSDSField.Value = 15;
            app.MaxGoodSDSField = uieditfield(app.INPUTVARIABLESPanel, 'numeric');
            app.MaxGoodSDSField.Limits = [0 Inf];
            app.MaxGoodSDSField.ValueDisplayFormat = '%2.1f';
            app.MaxGoodSDSField.ValueChangedFcn = createCallbackFcn(app, @MaxGoodSDSFieldValueChanged, true);
            app.MaxGoodSDSField.Value = 30;
            app.MaxSDSField = uieditfield(app.INPUTVARIABLESPanel, 'numeric');
            app.MaxSDSField.Limits = [0 Inf];
            app.MaxSDSField.ValueDisplayFormat = '%2.1f';
            app.MaxSDSField.ValueChangedFcn = createCallbackFcn(app, @MaxSDSFieldValueChanged, true);
            app.MaxSDSField.Value = 45;
            app.SaveADfileButton = uibutton(app.INPUTVARIABLESPanel, 'push');
            app.SaveADfileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveADfileButtonPushed, true);
            app.SaveADfileButton.Text = 'Save AD file';
            app.MinOptSepField = uieditfield(app.INPUTVARIABLESPanel, 'numeric');
            app.MinOptSepField.Limits = [0 Inf];
            app.MinOptSepField.ValueDisplayFormat = '%2.1f';
            app.MinOptSepField.ValueChangedFcn = createCallbackFcn(app, @MinOptSepFieldValueChanged, true);
            app.MinOptSepField.Value = 10;
            app.MinoptodesepmmLabel = uilabel(app.INPUTVARIABLESPanel);
            app.MinoptodesepmmLabel.Text = 'Min. optode sep.(mm)';
            app.MinOptSepSlider = uislider(app.INPUTVARIABLESPanel);
            app.MinOptSepSlider.Limits = [1 30];
            app.MinOptSepSlider.MajorTicks = [1 10 20 30 40 50];
            app.MinOptSepSlider.ValueChangingFcn = createCallbackFcn(app, @MinOptSepSliderValueChanging, true);
            app.MinOptSepSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50];
            app.MinOptSepSlider.Value = 10;
            app.SolutionSpaceLabel = uilabel(app.INPUTVARIABLESPanel);
            app.SolutionSpaceLabel.Text = 'Solution Space';
            app.SolSpaceButtonGroup = uibuttongroup(app.INPUTVARIABLESPanel);
            app.SolSpaceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SolSpaceButtonGroupSelectionChanged, true);
            app.Ten25Button = uiradiobutton(app.SolSpaceButtonGroup);
            app.Ten25Button.Text = 'Ten-2.5';
            app.Ten25Button.Value = true;
            app.Ten5Button = uiradiobutton(app.SolSpaceButtonGroup);
            app.Ten5Button.Text = 'Ten-5';
            app.LoadADfileButton = uibutton(app.INPUTVARIABLESPanel, 'push');
            app.LoadADfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadADfileButtonPushed, true);
            app.LoadADfileButton.Text = 'Load AD file';
            app.RunButton = uibutton(app.ArrayDesignerUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'RUN ARRAY DESIGNER';
            app.ExportResultstotxtButton = uibutton(app.ArrayDesignerUIFigure, 'push');
            app.ExportResultstotxtButton.ButtonPushedFcn = createCallbackFcn(app, @ExportResultstotxtButtonPushed, true);
            app.ExportResultstotxtButton.Text = 'Export Results to .txt';
            app.ArrayDesignerOutputInfoTextAreaLabel = uilabel(app.ArrayDesignerUIFigure);
            app.ArrayDesignerOutputInfoTextAreaLabel.Text = 'Array Designer Output Info:';
            app.OutputInfoText = uitextarea(app.ArrayDesignerUIFigure);
            app.OutputInfoText.Editable = 'off';
            app.ROIViewLabel = uilabel(app.ArrayDesignerUIFigure);
            app.ROIViewLabel.Text = 'ROI View';
            app.ArrayViewLabel = uilabel(app.ArrayDesignerUIFigure);
            app.ArrayViewLabel.Text = 'Array View';
            % Show the figure after all components are created
            app.ArrayDesignerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ArrayDesignerAPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ArrayDesignerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ArrayDesignerUIFigure)
        end
    end
end