classdef ROIBuilder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ROIBuilderUIFigure            matlab.ui.Figure
        ROIAxes                       matlab.ui.control.UIAxes
        Panel                         matlab.ui.container.Panel
        SelectHeadModelButton         matlab.ui.control.Button
        HeadModelPathText             matlab.ui.control.EditField
        SelectExistingROIButton       matlab.ui.control.Button
        CurrentROIText                matlab.ui.control.EditField
        SelectParcellationDropDownLabel  matlab.ui.control.Label
        SelectParcellationDropDown    matlab.ui.control.DropDown
        ROIBuilderLabel               matlab.ui.control.Label
        ParcelselectorPanel           matlab.ui.container.Panel
        ADDSelectedlistButton         matlab.ui.control.Button
        REMOVESelectedlistButton      matlab.ui.control.Button
        ParcelsallListBoxLabel        matlab.ui.control.Label
        ParcelsallListBox             matlab.ui.control.ListBox
        ParcelsincludedListBoxLabel   matlab.ui.control.Label
        ParcelsincludedListBox        matlab.ui.control.ListBox
        ADDSelectedfigButton          matlab.ui.control.Button
        REMOVESelectedfigButton       matlab.ui.control.Button
        BrainpainterPanel             matlab.ui.container.Panel
        BrushDiametermmSliderLabel    matlab.ui.control.Label
        BrushDiametermmSlider         matlab.ui.control.Slider
        PaintButton                   matlab.ui.control.Button
        EraseButton                   matlab.ui.control.Button
        BrushDiametermmSliderLabel_2  matlab.ui.control.Label
        SaveROIButton                 matlab.ui.control.Button
        MirrorROIButton               matlab.ui.control.Button
        MirrorinclradiusmmEditFieldLabel  matlab.ui.control.Label
        MirrorinclradiusmmEditField   matlab.ui.control.NumericEditField
        ClearROIButton                matlab.ui.control.Button
        ViewButtonGroup               matlab.ui.container.ButtonGroup
        ShowROIButton_2               matlab.ui.control.RadioButton
        ShowParcelsButton_2           matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        repoPath = fileparts(mfilename('fullpath'));
        pathnameHeadModel; %Head model directory
        pathnameROI;
        pathnameMSHS; %Actual head model mshs pathname
        gmSurfaceMesh;
        pathnameGMSurf;
        ROI = struct('gmNodeList',[],'solutionSpaceNodeList',[]);
        ROIvParcelsFlag = 1;
        ParcellationNumber = 1;
        BrushDiameter = 10;
    end
    
    
    methods (Access = private)
              
        function results = updateAll(app)
            updateVariables(app)
            updateFigures(app)
        end
         
        function results = updateVariables(app)
            %Update all the inputs components
            if ~isempty(app.pathnameHeadModel)
                [~,tmp,ext] = fileparts(app.pathnameHeadModel);
                app.HeadModelPathText.Value = [tmp];
            else
                app.HeadModelPathText.Value = '';
            end
            
            if ~isempty(app.pathnameROI)
                [~,tmp,ext] = fileparts(app.pathnameROI);
                app.CurrentROIText.Value = [tmp];
                app.ROI = loadROI(app.pathnameROI);
            else
                app.CurrentROIText.Value = '';
            end
            
            %Update mshs pathname
            if ~isempty(app.pathnameHeadModel)
                app.pathnameMSHS = getMSHSpath(app.pathnameHeadModel);
                %Update gmSurfaceMesh
                tmp = load(app.pathnameMSHS,'gmSurfaceMesh','-mat');
                app.gmSurfaceMesh = tmp.gmSurfaceMesh;
                
                %app.pathnameGMSurf = fullfile(app.pathnameHeadModel);
                %tmp = load(fullfile(app.pathnameGMSurf,'gmSurfaceMesh'),'-mat'); % changed by SB 
                %app.gmSurfaceMesh = tmp.gmSurfaceMesh;  % changed by SB 
            end

            %Update parcellation dropdown
            if isfield(app.gmSurfaceMesh,'parcellation')
                nParcellations = size(app.gmSurfaceMesh.parcellation);
                for i = 1:nParcellations
                    app.SelectParcellationDropDown.Items{i} = app.gmSurfaceMesh.parcellation(i).name;
                end
                app.SelectParcellationDropDown.Value = app.SelectParcellationDropDown.Items{1};
                SelectParcellationDropDownValueChanged(app);
            end
            
        end
        
        function results = updateFigures(app)
            
            if app.ROIvParcelsFlag
                %Update ROI plot
                cla(app.ROIAxes);
                if ~isempty(app.ROI) && ~isempty(app.gmSurfaceMesh)
                    plotROI(app.gmSurfaceMesh,app.ROI,'hAxes',app.ROIAxes);
                end
            elseif app.ROIvParcelsFlag==0
                %Update plot with parcels
                cla(app.ROIAxes);
                if ~isempty(app.gmSurfaceMesh)
                    plotParcellation(app.gmSurfaceMesh,app.ParcellationNumber,'hAxes',app.ROIAxes);
                end              
                
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, pathnameROI, pathnameHeadModel)
            if exist('pathnameROI','var')
                if ~isempty(pathnameROI)
                    app.pathnameROI = pathnameROI;
                end
            end
            if exist('pathnameHeadModel','var')
                if ~isempty(pathnameHeadModel)
                    app.pathnameHeadModel = pathnameHeadModel;
                end
            end
            
            updateAll(app)
            
        end

        % Button pushed function: SelectHeadModelButton
        function SelectHeadModelButtonPushed(app, event)
            [filename, pathname, ~] = uigetfile(fullfile(app.repoPath,'*.mshs'),'Select .mshs file');
            if filename==0
                return
            end
            fullpath = [pathname filename];
            [pathname,filename,ext] = fileparts(fullpath);
            pathnameHeadModeltmp = fullfile(pathname,filename);
            mshstmp = load([pathnameHeadModeltmp ext],'-mat');
            
            makeSolutionSpaceFlag = 0;
            %Check if there is a folder pwd/HeadModels/filename. If not,
            %create and save mshs there
            if ~exist(fullfile(app.repoPath,'HeadModels',filename),'dir')
               mkdir(fullfile(app.repoPath,'HeadModels',filename));
               save(pathnameHeadModeltmp,'-struct','mshstmp');
               app.pathnameHeadModel = fullfile(app.repoPath,'HeadModels',filename);
               
               %Also make ROI directory
               mkdir(fullfile(app.repoPath,'HeadModels',filename,'ROIs'));
               
               %Make scalpPos.txt (solution space)
               makeSolutionSpaceFlag = 1;
           
            %If headmodel exists with this name, but specified path is different. Warn and overwrite
            elseif ~strcmpi(fullfile(app.repoPath,'HeadModels',filename,filename),pathnameHeadModeltmp)  
                selection = uiconfirm(app.ROIBuilderUIFigure,'A head model with this name already exists in ArrayDesigner','Head Model','Options',{'Use Existing','Overwrite Existing'},'DefaultOption',1,...
                        'Icon','warning');
                    
                    if strcmpi(selection,'Use Existing')
                        app.pathnameHeadModel = fullfile(app.repoPath,'HeadModels',filename);
                        if ~exist(fullfile(app.repoPath,'HeadModels',filename,'Utils','scalpSolutionSpace_10_2p5.txt'),'file')
                            makeSolutionSpaceFlag = 1;
                        end
                    elseif strcmpi(selection,'Overwrite Existing')
                        save(fullfile(app.repoPath,'HeadModels',[filename ext]),'-struct','mshstmp');
                        app.pathnameHeadModel = fullfile(app.repoPath,'HeadModels',filename);
                        if ~exist(fullfile(app.repoPath,'HeadModels',filename,'Utils','scalpSolutionSpace_10_2p5.txt'),'file')
                            makeSolutionSpaceFlag = 1;
                        end
                    end
            else %Headmodel is existing one selected from HeadModels folder
                %Specify pathnameHeadModel as that selected. Double check
                %solution space exists
                app.pathnameHeadModel = fullfile(app.repoPath,'HeadModels',filename);
                if ~exist(fullfile(app.repoPath,'HeadModels',filename,'Utils','scalpSolutionSpace_10_2p5.txt'),'file')
                    makeSolutionSpaceFlag = 1;
                end
            end
            
            %Update text field
            app.HeadModelPathText.Value = app.pathnameHeadModel;
            
            %Update mshs pathname
            app.pathnameMSHS = fullfile(app.pathnameHeadModel,[filename ext]);
            
            %Load gmSurfaceMesh
            tmp = load(app.pathnameMSHS,'gmSurfaceMesh','-mat');
            if length(tmp.gmSurfaceMesh.node)>20e3
                warning('The selected mesh has a GM surface of >20,000 nodes. We recommend downsampling this mesh to maintain speed');
            end
            app.gmSurfaceMesh = tmp.gmSurfaceMesh;
            
            %If needed make solution space
            if makeSolutionSpaceFlag
                selection = uiconfirm(app.ROIBuilderUIFigure,'The selected head model has no defined scalp solution space, would you like to compute this now?','Solution space','Options',{'Yes','Cancel'},'DefaultOption',1,...
                    'Icon','warning');
                if strcmpi(selection,'Yes')
                    %Create solution space - scalpSolutionSpace_10_2p5.txt
                    getSolutionSpace(app.pathnameHeadModel,app.pathnameMSHS);
                    
                    %Write gmPos.txt
                    writeGMPos(app.gmSurfaceMesh,app.pathnameHeadModel);
                    
                end
            end
            
            %Clear ROI text field
            app.CurrentROIText.Value = '';
            app.pathnameROI = '';
            app.ROI = struct('gmNodeList',[],'scalpPosList',[]);
            
            %Update axes
            updateFigures(app);
            
            %Update parcellation dropdown
            if isfield(app.gmSurfaceMesh,'parcellation')
                nParcellations = size(app.gmSurfaceMesh.parcellation);
                for i = 1:nParcellations
                    app.SelectParcellationDropDown.Items{i} = app.gmSurfaceMesh.parcellation(i).name;
                end
                app.SelectParcellationDropDown.Value = app.SelectParcellationDropDown.Items{1};
                SelectParcellationDropDownValueChanged(app);
            end
            
           
        end

        % Button pushed function: SelectExistingROIButton
        function SelectExistingROIButtonPushed(app, event)
            [filename, pathname, ~] = uigetfile(fullfile(app.repoPath,'*.txt'),'Select ROI text file (should be within the relevant HeadModels directory in your Array Designer folder)');
            if filename==0
                return
            end
            fullpath = [pathname filename];
            [pathname,filename,ext] = fileparts(fullpath);
            app.pathnameROI = fullfile(pathname,[filename ext]);
            app.ROI = loadROI(app.pathnameROI);
            app.CurrentROIText.Value = [filename ext];
            
            %Determine headmodel from ROI location (if possible)
            ind = strfind(app.pathnameROI,'ROIs');
            pathnameHeadModeltmp = app.pathnameROI(1:ind-1);
            if unix
                ind2 = strfind(pathnameHeadModeltmp,'/');
                ind2 = ind2(end-1)+1;
            else
                ind2 = strfind(pathnameHeadModeltmp,'\');
                ind2 = ind2(end-1)+1;
            end

            mshsname = [pathnameHeadModeltmp(ind2:end-1) '.mshs'];
            
            if exist(fullfile(pathnameHeadModeltmp,mshsname),'file')
                app.pathnameHeadModel = pathnameHeadModeltmp;
                app.pathnameMSHS = fullfile(pathnameHeadModeltmp,mshsname);
                mshstmp = load(fullfile(pathnameHeadModeltmp,mshsname),'-mat');
            else
                uialert(app.ROIBuilderUIFigure,'The head model associated with the selected ROI cannot be found. Any selected ROI .txt file should be in the /ROIs subdirectory associated with a given head model.','Missing Head Model');
                return
            end
            
            %Update text field
            app.HeadModelPathText.Value = app.pathnameHeadModel;
            
            %Load gmSurfaceMesh
            app.gmSurfaceMesh = mshstmp.gmSurfaceMeshDownsampled;  % CHanged by SB
            
            %Update axes
            updateFigures(app);
            
            %Update parcellation dropdown
            if isfield(app.gmSurfaceMesh,'parcellation')
                nParcellations = size(app.gmSurfaceMesh.parcellation);
                for i = 1:nParcellations
                    app.SelectParcellationDropDown.Items{i} = app.gmSurfaceMesh.parcellation(i).name;
                end
                app.SelectParcellationDropDown.Value = app.SelectParcellationDropDown.Items{1};
                SelectParcellationDropDownValueChanged(app);
            end
        end

        % Value changed function: SelectParcellationDropDown
        function SelectParcellationDropDownValueChanged(app, event)
            value = app.SelectParcellationDropDown.Value;
            %Update parcelsavailable list
            ind = find(strcmp(app.SelectParcellationDropDown.Items,app.SelectParcellationDropDown.Value));
            app.ParcellationNumber = ind;
            app.ParcelsallListBox.Items = app.gmSurfaceMesh.parcellation(ind).labels;
        end

        % Button pushed function: ADDSelectedlistButton
        function ADDSelectedlistButtonPushed(app, event)
            currList = app.ParcelsincludedListBox.Items;
            added = app.ParcelsallListBox.Value;
            toAdd = {};
            count = 1;
            for i = 1:length(added)
                if ~any(ismember(app.ParcelsincludedListBox.Items,added{i}))
                    toAdd{count} = added{i};
                    %UPDATE ROI
                    parcelIndex = find(ismember(app.gmSurfaceMesh.parcellation(app.ParcellationNumber).labels,added{i}));
                    nodes2add = find(app.gmSurfaceMesh.parcellation(app.ParcellationNumber).index==parcelIndex);
                    app.ROI.gmNodeList = unique([app.ROI.gmNodeList; nodes2add]);
                    count = count + 1;
                end
            end
            app.ParcelsincludedListBox.Items = [currList toAdd];
            
            app.ShowROIButton_2.Value = 1;
            app.ROIvParcelsFlag = 1;
            updateFigures(app)
            
        end

        % Button pushed function: REMOVESelectedlistButton
        function REMOVESelectedlistButtonPushed(app, event)
            currList = app.ParcelsincludedListBox.Items;
            toRem = app.ParcelsincludedListBox.Value;
            for i = 1:length(toRem)
                currList = currList(~ismember(currList,toRem{i}));
                %UPDATE ROI
                parcelIndex = find(ismember(app.gmSurfaceMesh.parcellation(app.ParcellationNumber).labels,toRem{i}));
                nodes2rem = find(app.gmSurfaceMesh.parcellation(app.ParcellationNumber).index==parcelIndex);
                app.ROI.gmNodeList = app.ROI.gmNodeList(~ismember(app.ROI.gmNodeList,nodes2rem));
            end
            app.ParcelsincludedListBox.Items = currList;
            
            app.ShowROIButton_2.Value = 1;
            app.ROIvParcelsFlag = 1;
            updateFigures(app)
            
        end

        % Selection changed function: ViewButtonGroup
        function ROIParcelToggle(app, event)
            selectedButton = app.ViewButtonGroup.SelectedObject;
            if strcmpi(selectedButton.Text,'Show Parcels')
                app.ROIvParcelsFlag = 0;
                updateFigures(app)
            elseif strcmpi(selectedButton.Text,'Show ROI')
                app.ROIvParcelsFlag = 1;
                updateFigures(app)
            end
        end

        % Button pushed function: ADDSelectedfigButton
        function ADDSelectedfigButtonPushed(app, event)
            dcObj = findall(app.ROIAxes,'Type','hggroup');
            if ~isempty(dcObj)
                pos = dcObj(1).Cursor.Position;
                % ###### Now find the parcel associated with this position and add
                %it to the ROI, then update figure. Do equivalent for
                %remove button. Also use same approach in brain painter
                %setting
                [~,posInd] = min(sum(abs(app.gmSurfaceMesh.node - repmat(pos,length(app.gmSurfaceMesh.node),1)),2));
                parcelIndex = app.gmSurfaceMesh.parcellation(app.ParcellationNumber).index(posInd);
                nodes2add = find(app.gmSurfaceMesh.parcellation(app.ParcellationNumber).index==parcelIndex);
                app.ROI.gmNodeList = unique([app.ROI.gmNodeList; nodes2add]);
                
                %Add parcel to text list
                currList = app.ParcelsincludedListBox.Items;
                toAdd = app.gmSurfaceMesh.parcellation(app.ParcellationNumber).labels{parcelIndex};
                app.ParcelsincludedListBox.Items = [currList toAdd];
                
                app.ShowROIButton_2.Value = 1;
                app.ROIvParcelsFlag = 1;
                updateFigures(app)
                
            else
                msgbox('Select a point on the brain before attempting to add parcel');
            end


        end

        % Button pushed function: REMOVESelectedfigButton
        function REMOVESelectedfigButtonPushed(app, event)
            dcObj = findall(app.ROIAxes,'Type','hggroup');
            if ~isempty(dcObj)
                pos = dcObj(1).Cursor.Position;
                % ###### Now find the parcel associated with this position and add
                %it to the ROI, then update figure. Do equivalent for
                %remove button. Also use same approach in brain painter
                %setting
                [~,posInd] = min(sum(abs(app.gmSurfaceMesh.node - repmat(pos,length(app.gmSurfaceMesh.node),1)),2));
                parcelIndex = app.gmSurfaceMesh.parcellation(app.ParcellationNumber).index(posInd);
                nodes2rem = find(app.gmSurfaceMesh.parcellation(app.ParcellationNumber).index==parcelIndex);
                app.ROI.gmNodeList = app.ROI.gmNodeList(~ismember(app.ROI.gmNodeList,nodes2rem));
                
                %remove from text lists
                currList = app.ParcelsincludedListBox.Items;
                toRem = app.gmSurfaceMesh.parcellation(app.ParcellationNumber).labels{parcelIndex};
                currList = currList(~ismember(currList,toRem));
                app.ParcelsincludedListBox.Items = currList;
                
                app.ShowROIButton_2.Value = 1;
                app.ROIvParcelsFlag = 1;
                updateFigures(app)
                
            else
                msgbox('Select a point on the brain before attempting to remove parcel','Select point!');
            end
            
        end

        % Value changed function: BrushDiametermmSlider
        function BrushDiametermmSliderValueChanged(app, event)
            value = app.BrushDiametermmSlider.Value;
            app.BrushDiameter = value;
        end

        % Button pushed function: PaintButton
        function PaintButtonPushed(app, event)
            dcObj = findall(app.ROIAxes,'Type','hggroup');
            if ~isempty(dcObj)
                pos = dcObj(1).Cursor.Position;
                % ###### Now find nodes within diameter/2 of pos
                rad = app.BrushDiameter/2;
                nodes2add = find(sqrt(sum((app.gmSurfaceMesh.node(:,1:3) - repmat(pos,length(app.gmSurfaceMesh.node),1)).^2,2)) <= rad);
                app.ROI.gmNodeList = unique([app.ROI.gmNodeList; nodes2add]);
                
                app.ShowROIButton_2.Value = 1;
                app.ROIvParcelsFlag = 1;
                updateFigures(app)
            else
                msgbox('Select a point on the brain to paint before hitting Add');
            end
            
        end

        % Button pushed function: EraseButton
        function EraseButtonPushed(app, event)
            dcObj = findall(app.ROIAxes,'Type','hggroup');
            if ~isempty(dcObj)
                pos = dcObj(1).Cursor.Position;
                % ###### Now find nodes within diameter/2 of pos
                rad = app.BrushDiameter/2;
                nodes2rem = find(sqrt(sum((app.gmSurfaceMesh.node - repmat(pos,length(app.gmSurfaceMesh.node),1)).^2,2)) <= rad);
                app.ROI.gmNodeList = app.ROI.gmNodeList(~ismember(app.ROI.gmNodeList,nodes2rem));
                
                app.ShowROIButton_2.Value = 1;
                app.ROIvParcelsFlag = 1;
                updateFigures(app)
            else
                msgbox('Select a point on the brain to paint before hitting Erase');
            end
      
        end

        % Button pushed function: SaveROIButton
        function SaveROIButtonPushed(app, event)
            [filename,pathname,ext] = uiputfile(fullfile(app.pathnameHeadModel,'ROIs','*.txt'),'Save ROI .txt file');
            if filename==0
                return
            end
            fullpath = [pathname filename ext];
            
            %Select solution space and create subset positions
            % TO DO - make it possible to select solutions space (10-5 or
            % 10-2p5 etc)
            if ~exist(fullfile(app.pathnameHeadModel,'Utils','scalpSolutionSpace_10_2p5.txt'),"file")
                selection = uiconfirm(app.ROIBuilderUIFigure,'The selected head model has no defined scalp solution space, would you like to compute this now?','Solution space','Options',{'Yes','Cancel'},'DefaultOption',1,...
                    'Icon','warning');
                if strcmpi(selection,'Yes')
                    %Create solution space - scalpSolutionSpace_10_2p5.txt
                    getTen2p5(app.pathnameHeadModel,app.pathnameMSHS);
                    
                    %Write gmPos.txt
                    writeGMPos(app.gmSurfaceMesh,app.pathnameHeadModel);
                end
            end
            
            %Create solution space subset associated with this ROI
            scalpSolSpace = load(fullfile(app.pathnameHeadModel,'Utils','scalpSolutionSpace_10_2p5.txt'));
            maxRho = 30; %Temporary hard coding.
            [~, solSpaceSubsetInd] = getScalpSolSpaceSubset(app.gmSurfaceMesh,scalpSolSpace,app.ROI.gmNodeList,maxRho);
            app.ROI.solutionSpaceNodeList = solSpaceSubsetInd;
            
            writeROI(app.ROI,fullpath);
            app.pathnameROI = fullpath;
            app.CurrentROIText.Value = app.pathnameROI;
        end

        % Button pushed function: MirrorROIButton
        function MirrorROIButtonPushed(app, event)
            %Force current ROI to become bilaterally symmetric
            radius = app.MirrorinclradiusmmEditField.Value;
            currROIList = app.ROI.gmNodeList;
            currROI = app.gmSurfaceMesh.node(app.ROI.gmNodeList,1:3);
            currROIflip = currROI;
            currROIflip(:,1) = currROI(:,1)*-1; %Flip about x
            nCurrROI = length(currROI);
            nPosGM = length(app.gmSurfaceMesh.node);
            
            %Create distMAT
            distMat = zeros(nCurrROI*2,nPosGM);
            wb = waitbar(0,'Mirroring ROI...');
            for ii = 1:nCurrROI
                if exist('wb','var')
                    waitbar(ii/nCurrROI,wb);
                else
                    return
                end
                
                dists = sqrt(sum((app.gmSurfaceMesh.node(:,1:3) - repmat(currROIflip(ii,:),nPosGM,1)).^2,2));
                distMat(ii,:) = dists;
                %Include ipsilateral points to maintain size and
                %prevent hemisphere ROIs growing alterntely
                dists = sqrt(sum((app.gmSurfaceMesh.node(:,1:3) - repmat(currROI(ii,:),nPosGM,1)).^2,2));
                distMat(ii+nCurrROI,:) = dists;                
            end
            
            toAdd = find(any(distMat<=radius,1))';
            currROIList = [currROIList; toAdd];
            
            if exist('wb','var'); delete(wb); end
            
            app.ROI.gmNodeList = currROIList;
            figure(app.ROIBuilderUIFigure);
            updateFigures(app);           
            
%             wb = waitbar(0,'Mirroring ROI...');
%             for i = 1:length(currROI)
%                 tmpNode = currROI(i,:);
%                 tmpNode2 = tmpNode;
%                 tmpNode2(1) = -tmpNode(1);
%                 dists = sqrt(sum((app.gmSurfaceMesh.node(:,1:3) - repmat(tmpNode2,nPosGM,1)).^2,2));
%                 currROIList = [currROIList; find(dists<= radius)];
%                 %Do the same for ipsilateral nodes to keep selected regions
%                 %the same diameter (prevent them growing unevenly)
%                 dists = sqrt(sum((app.gmSurfaceMesh.node(:,1:3) - repmat(tmpNode,nPosGM,1)).^2,2));
%                 currROIList = [currROIList; find(dists<= radius)];
%                 if exist('wb','var')
%                     waitbar(i/nCurrROI,wb);
%                 else
%                     return
%                 end
%             end
%             if exist('wb','var'); delete(wb); end
%             app.ROI.gmNodeList = currROIList;
%             figure(app.ROIBuilderUIFigure);
%             updateFigures(app);
            
        end

        % Button pushed function: ClearROIButton
        function ClearROIButtonPushed(app, event)
            app.ROI.gmNodeList = [];
            app.ROI.solutionSpaceNodeList = [];
            app.pathnameROI = [];
            updateAll(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ROIBuilderUIFigure = uifigure('Visible', 'off');
            app.ROIBuilderUIFigure.Name = 'ROI Builder';
            app.ROIAxes = uiaxes(app.ROIBuilderUIFigure);
            title(app.ROIAxes, '')
            xlabel(app.ROIAxes, 'X (mm)')
            ylabel(app.ROIAxes, 'Y (mm)')
            app.Panel = uipanel(app.ROIBuilderUIFigure);
            app.SelectHeadModelButton = uibutton(app.Panel, 'push');
            app.SelectHeadModelButton.ButtonPushedFcn = createCallbackFcn(app, @SelectHeadModelButtonPushed, true);
            app.SelectHeadModelButton.Text = 'Select Head Model';
            app.HeadModelPathText = uieditfield(app.Panel, 'text');
            app.HeadModelPathText.Editable = 'off';
            app.HeadModelPathText.Tooltip = {'Select the head model (.mshs file) in which you want to specify your ROI'};
            app.SelectExistingROIButton = uibutton(app.Panel, 'push');
            app.SelectExistingROIButton.ButtonPushedFcn = createCallbackFcn(app, @SelectExistingROIButtonPushed, true);
            app.SelectExistingROIButton.Text = 'Select Existing ROI';
            app.CurrentROIText = uieditfield(app.Panel, 'text');
            app.CurrentROIText.Editable = 'off';
            app.CurrentROIText.Tooltip = {'Select an exisiting ROI file to edit (.txt)'};
            app.SelectParcellationDropDownLabel = uilabel(app.Panel);
            app.SelectParcellationDropDownLabel.Text = 'Select Parcellation';
            app.SelectParcellationDropDown = uidropdown(app.Panel);
            app.SelectParcellationDropDown.Items = {};
            app.SelectParcellationDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectParcellationDropDownValueChanged, true);
            app.SelectParcellationDropDown.Tooltip = {'Choose a parcellation from those present within the selected head model .gmSurfaceMesh'};
            app.SelectParcellationDropDown.Value = {};
            app.ROIBuilderLabel = uilabel(app.ROIBuilderUIFigure);
            app.ROIBuilderLabel.Text = 'ROI Builder';
            app.ParcelselectorPanel = uipanel(app.ROIBuilderUIFigure);
            app.ParcelselectorPanel.Title = 'Parcel selector';
            app.ADDSelectedlistButton = uibutton(app.ParcelselectorPanel, 'push');
            app.ADDSelectedlistButton.ButtonPushedFcn = createCallbackFcn(app, @ADDSelectedlistButtonPushed, true);
            app.ADDSelectedlistButton.Tooltip = {'Add the selected parcels to your ROI'};
            app.ADDSelectedlistButton.Text = 'ADD Selected (list) >';
            app.REMOVESelectedlistButton = uibutton(app.ParcelselectorPanel, 'push');
            app.REMOVESelectedlistButton.ButtonPushedFcn = createCallbackFcn(app, @REMOVESelectedlistButtonPushed, true);
            app.REMOVESelectedlistButton.Tooltip = {'Remove the selected parcels from your ROI'};
            app.REMOVESelectedlistButton.Text = '< REMOVE Selected (list)';
            app.ParcelsallListBoxLabel = uilabel(app.ParcelselectorPanel);
            app.ParcelsallListBoxLabel.Text = 'Parcels (all) ';
            app.ParcelsallListBox = uilistbox(app.ParcelselectorPanel);
            app.ParcelsallListBox.Items = {};
            app.ParcelsallListBox.Multiselect = 'on';
            app.ParcelsallListBox.Tooltip = {'Listed here are all the parcels in the seelcted parcellation'};
            app.ParcelsallListBox.Value = {};
            app.ParcelsincludedListBoxLabel = uilabel(app.ParcelselectorPanel);
            app.ParcelsincludedListBoxLabel.Text = 'Parcels (included)';
            app.ParcelsincludedListBox = uilistbox(app.ParcelselectorPanel);
            app.ParcelsincludedListBox.Items = {};
            app.ParcelsincludedListBox.Multiselect = 'on';
            app.ParcelsincludedListBox.Tooltip = {'Listed here are all the parcels you have selected as part fo your ROI'};
            app.ParcelsincludedListBox.Value = {};
            app.ADDSelectedfigButton = uibutton(app.ParcelselectorPanel, 'push');
            app.ADDSelectedfigButton.ButtonPushedFcn = createCallbackFcn(app, @ADDSelectedfigButtonPushed, true);
            app.ADDSelectedfigButton.Tooltip = {'Add the selected parcels to your ROI'};
            app.ADDSelectedfigButton.Text = 'ADD Selected (fig) >';
            app.REMOVESelectedfigButton = uibutton(app.ParcelselectorPanel, 'push');
            app.REMOVESelectedfigButton.ButtonPushedFcn = createCallbackFcn(app, @REMOVESelectedfigButtonPushed, true);
            app.REMOVESelectedfigButton.Tooltip = {'Remove the selected parcels from your ROI'};
            app.REMOVESelectedfigButton.Text = '< REMOVE Selected (fig)';
            app.BrainpainterPanel = uipanel(app.ROIBuilderUIFigure);
            app.BrainpainterPanel.Title = 'Brain painter';
            app.BrushDiametermmSliderLabel = uilabel(app.BrainpainterPanel);
            app.BrushDiametermmSliderLabel.Text = 'Brush Diameter (mm)';
            app.BrushDiametermmSlider = uislider(app.BrainpainterPanel);
            app.BrushDiametermmSlider.Limits = [0 50];
            app.BrushDiametermmSlider.ValueChangedFcn = createCallbackFcn(app, @BrushDiametermmSliderValueChanged, true);
            app.BrushDiametermmSlider.Value = 10;
            app.PaintButton = uibutton(app.BrainpainterPanel, 'push');
            app.PaintButton.ButtonPushedFcn = createCallbackFcn(app, @PaintButtonPushed, true);
            app.PaintButton.Text = 'Paint';
            app.EraseButton = uibutton(app.BrainpainterPanel, 'push');
            app.EraseButton.ButtonPushedFcn = createCallbackFcn(app, @EraseButtonPushed, true);
            app.EraseButton.Text = 'Erase';
            app.BrushDiametermmSliderLabel_2 = uilabel(app.BrainpainterPanel);
            app.BrushDiametermmSliderLabel_2.Text = 'Brush stroke:';
            app.SaveROIButton = uibutton(app.ROIBuilderUIFigure, 'push');
            app.SaveROIButton.ButtonPushedFcn = createCallbackFcn(app, @SaveROIButtonPushed, true);
            app.SaveROIButton.Text = 'Save ROI';
            app.MirrorROIButton = uibutton(app.ROIBuilderUIFigure, 'push');
            app.MirrorROIButton.ButtonPushedFcn = createCallbackFcn(app, @MirrorROIButtonPushed, true);
            app.MirrorROIButton.Text = 'Mirror ROI';
            app.MirrorinclradiusmmEditFieldLabel = uilabel(app.ROIBuilderUIFigure);
            app.MirrorinclradiusmmEditFieldLabel.Text = {'Mirror incl. '; 'radius (mm)'};
            app.MirrorinclradiusmmEditField = uieditfield(app.ROIBuilderUIFigure, 'numeric');
            app.MirrorinclradiusmmEditField.Value = 2;
            app.ClearROIButton = uibutton(app.ROIBuilderUIFigure, 'push');
            app.ClearROIButton.ButtonPushedFcn = createCallbackFcn(app, @ClearROIButtonPushed, true);
            app.ClearROIButton.Text = 'Clear ROI';
            app.ViewButtonGroup = uibuttongroup(app.ROIBuilderUIFigure);
            app.ViewButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ROIParcelToggle, true);
            app.ViewButtonGroup.Title = 'View';
            app.ShowROIButton_2 = uiradiobutton(app.ViewButtonGroup);
            app.ShowROIButton_2.Text = 'Show ROI';
            app.ShowROIButton_2.Value = true;
            app.ShowParcelsButton_2 = uiradiobutton(app.ViewButtonGroup);
            app.ShowParcelsButton_2.Text = 'Show Parcels';
            % Show the figure after all components are created
            app.ROIBuilderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ROIBuilder(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ROIBuilderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ROIBuilderUIFigure)
        end
    end
end