classdef ROIdesigner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        UIAxes          matlab.ui.control.UIAxes
        EditFieldLabel  matlab.ui.control.Label
        EditField       matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load('/Users/sabri/Documents/code/ArrayDesigner/ArrayDesigner/HeadModels/MNI152_LowRes/GMSurfaceMesh.mat')
            trisurf(GMSurfaceMesh.face(:,1:3),GMSurfaceMesh.node(:,1),GMSurfaceMesh.node(:,2),GMSurfaceMesh.node(:,3),'FaceColor',[0.5 0.5 0.5],'Parent',app.UIAxes)
            rotate3d(app.UIAxes,'on')
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            ac=[];
            %b=erase(a,{'[',']'});
            a=split(value,{' ',','});
            ac = str2double(a)';  
            plot3(app.UIAxes,value(1),value(2),value(3),'.r')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.CLim = [0 1];
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.ZTick = [0 0.5 1];
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = ROIdesigner

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end